classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GRAPHButton  matlab.ui.control.Button
        RESETButton  matlab.ui.control.Button
        UIAxes6      matlab.ui.control.UIAxes
        UIAxes5      matlab.ui.control.UIAxes
        UIAxes4      matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, GUI1)
          
        end

        % Callback function
        function EvaluateweightFandmassmButtonPushed(app, event)
          
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
         
        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)
sym i
t1 = 600;
t2 = 600;
t3 = 600;
t11 = 600;
t21 = 600;
t31 = 600;
t12 = 600;
t22 = 600;
t32 = 600;
t13 = 600;
t23 = 600;
t33 = 600;
th1 = 10;
th2 = 10;
th3 = 10;
th11 = 10;
th21 = 10;
th31 = 10;
th12 = 10;
th22 = 10;
th32 = 10;
th13 = 10;
th23 = 10;
th33 = 10;
th14 = 10;
th24 = 10;
th34 = 10;
th15 = 10;
th25 = 10;
th35 = 10;
A = zeros(1,15);
B = zeros(1,15);
C = zeros(1,15);
F1 = zeros(1,15);
D = zeros(1,15);
E = zeros(1,15);
G = zeros(1,15);
F2 = zeros(1,15);
F3 = zeros(1,15);
F4 = zeros(1,15);
F5 = zeros(1,15);
F6 = zeros(1,15);
for i = 1:1400
    if t11<=2000
        P = t11*sind(th1) + t2*cosd(90 - th2) + t3*cosd(90 - th3);
        A(i) = P;
        F1(i) = t11;
    end
    t11=t11+100;
    th1 = 10;
    th2 = 10;
    th3 = 10;
end
for i = 1:1400
    if t21<=2000
        P = t1*sind(th11) + t21*cosd(90 - th21) + t3*cosd(90 - th31);
        B(i) = P;
        F2(i) = t21;
    end
    t21 = t21+100;
    th11 = th11+10;
    th21 = th21+10;
    th31 = th31+10;
end
for i = 1:1400
    if t31<=2000
        P = t1*sind(th12) + t2*cosd(90 - th22) + t31*cosd(90 - th32);
        C(i) = P;
        F3(i) = t31;
    end
    t31 = t31+100;
    th12 = th12+10;
    th22 = th22+10;
    th32 = th32+10;
end
for i = 1:50
    if th13<=60
        P = t1*sind(th13) + t21*cosd(90 - th23) + t3*cosd(90 - th33);
        D(i) = P;
        F4(i) = th13;
    end
    th13 = th13+10;
    t1 = t1+100;
    t2 = t2+100;
    t3 = t3+100;
end
for i = 1:50
    if th24<=60
        P = t1*sind(th14) + t21*cosd(90 - th24) + t3*cosd(90 - th34);
        E(i) = P;
        F5(i) = th24;
    end
    th24 = th24+10;
    t12 = t12+100;
    t22 = t22+100;
    t32 = t32+100;
end
for i = 1:50
    if th35<=60
        P = t13*sind(th15) + t23*cosd(90 - th25) + t33*cosd(90 - th35);
        G(i) = P;
        F6(i) = th35;
    end
    th35 = th35+10;
    t13 = t13+100;
    t23 = t23+100;
    t33 = t33+100;
end
plot(app.UIAxes,A,F1);
plot(app.UIAxes2,B,F2);
plot(app.UIAxes3,C,F3);
plot(app.UIAxes4,D,F4);
plot(app.UIAxes5,E,F5);
plot(app.UIAxes6,G,F6);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph-1')
            xlabel(app.UIAxes, 'Weight of block')
            ylabel(app.UIAxes, 'T1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Graph-3')
            xlabel(app.UIAxes3, 'Weight of block')
            ylabel(app.UIAxes3, 'T3')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Graph-2')
            xlabel(app.UIAxes2, 'Weight of block')
            ylabel(app.UIAxes2, 'T2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Graph-4')
            xlabel(app.UIAxes4, 'Weight of block')
            ylabel(app.UIAxes4, 'Angle 1')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Graph-5')
            xlabel(app.UIAxes5, 'Weight of block')
            ylabel(app.UIAxes5, 'Angle 2')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Graph-6')
            xlabel(app.UIAxes6, 'Weight of block')
            ylabel(app.UIAxes6, 'Angle 3')
            zlabel(app.UIAxes6, 'Z')
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.Text = {'RESET'; ''};
            app.GRAPHButton = uibutton(app.UIFigure, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = {'GRAPH'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end