classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GRAPHButton                    matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        EvaluateweightFandmassmButton  matlab.ui.control.Button
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        Fmgg981Label                   matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        EditField_3Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        EditField_2Label               matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        FT1sin1T2cos902T3cos903Label   matlab.ui.control.Label
        T3EditField                    matlab.ui.control.NumericEditField
        T3EditFieldLabel               matlab.ui.control.Label
        T2EditField                    matlab.ui.control.NumericEditField
        T2EditFieldLabel               matlab.ui.control.Label
        T1EditField                    matlab.ui.control.NumericEditField
        T1EditFieldLabel               matlab.ui.control.Label
        WeightofblockEditField         matlab.ui.control.EditField
        WeightofblockEditFieldLabel    matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, GUI1)
          
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            value = app.T1EditField.Value;
            
        end

        % Button pushed function: EvaluateweightFandmassmButton
        function EvaluateweightFandmassmButtonPushed(app, event)
            a = app.T1EditField.Value;
            b = app.T2EditField.Value;
            c = app.T3EditField.Value;
            d = str2num(app.EditField.Value);
            e = str2num(app.EditField_2.Value);
            f = str2num(app.EditField_3.Value);
            W = string(a*sind(d) + b*cosd(90 - e) + c*cosd(90 - f));
            app.WeightofblockEditField.Value = W;
            W1 = a*sind(d) + b*cosd(90 - e) + c*cosd(90 - f); 
            g = 9.81;
            m = W1/g;
            app.MassEditField.Value = m;
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            value = app.T2EditField.Value;
            
        end

        % Value changed function: T3EditField
        function T3EditFieldValueChanged(app, event)
            value = app.T3EditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changed function: WeightofblockEditField
        function WeightofblockEditFieldValueChanged(app, event)
            value = app.WeightofblockEditField.Value;
            
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
          
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            a = app.T1EditField.Value;
            b = app.T2EditField.Value;
            c = app.T3EditField.Value;
            d = str2num(app.EditField.Value);
            e = str2num(app.EditField_2.Value);
            f = str2num(app.EditField_3.Value);
            W = a*sind(d) + b*cosd(90 - e) + c*cosd(90 - f);
            m = W/9.81;
            X = W;
            Y = a;
            plot(app.UIAxes,X,"LineStyle","-");
        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)
            syms t11 t21 t31
            sym i
            t1 = app.T1EditField.Value;
            t2 = app.T2EditField.Value;
            t3 = app.T3EditField.Value;
            th1 = str2num(app.EditField.Value);
            th2 = str2num(app.EditField_2.Value);
            th3 = str2num(app.EditField_3.Value);
t11 = 600;
t21 = 600;
t31 = 600;
A = zeros(1,15);
B = zeros(1,15);
C = zeros(1,15);
F = zeros(1,15);

for i = 1:1400
    if t11<=2000
        P = t11*sind(th1) + t2*cosd(90 - th2) + t3*cosd(90 - th3);
        A(i) = P;
        F(i) = t11;
    end
    t11 = t11+100;
end
for i = 1:1400
    if t21<=2000
        P = t1*sind(th1) + t21*cosd(90 - th2) + t3*cosd(90 - th3);
        B(i) = P;
    end
    t21 = t21+100;
end
for i = 1:1400
    if t31<=2000
        P = t1*sind(th1) + t2*cosd(90 - th2) + t31*cosd(90 - th3);
        C(i) = P;
    end
    t31 = t31+100;
end
plot(app.UIAxes,A,F);
plot(app.UIAxes2,B,F);
plot(app.UIAxes3,C,F);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.WeightofblockEditFieldLabel = uilabel(app.UIFigure);
            app.WeightofblockEditFieldLabel.Text = {'Weight of block'; ''};
            app.WeightofblockEditField = uieditfield(app.UIFigure, 'text');
            app.WeightofblockEditField.ValueChangedFcn = createCallbackFcn(app, @WeightofblockEditFieldValueChanged, true);
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T3EditFieldLabel = uilabel(app.UIFigure);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.UIFigure, 'numeric');
            app.T3EditField.ValueChangedFcn = createCallbackFcn(app, @T3EditFieldValueChanged, true);
            app.FT1sin1T2cos902T3cos903Label = uilabel(app.UIFigure);
            app.FT1sin1T2cos902T3cos903Label.Text = 'F = T1sinθ1 + T2cos(90 - θ2) + T3cos(90-θ3)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'θ1'; ''};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'θ2';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'θ3';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Fmgg981Label = uilabel(app.UIFigure);
            app.Fmgg981Label.Text = {'F = mg g = 9.81'; ''};
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = {'Mass'; ''};
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.EvaluateweightFandmassmButton = uibutton(app.UIFigure, 'push');
            app.EvaluateweightFandmassmButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateweightFandmassmButtonPushed, true);
            app.EvaluateweightFandmassmButton.Text = {'Evaluate weight (F) '; 'and mass (m)'};
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.Text = {'RESET'; ''};
            app.GRAPHButton = uibutton(app.UIFigure, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = {'GRAPH'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end